classdef ar_homographies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        FILESLabel             matlab.ui.control.Label
        SourceFileButton       matlab.ui.control.Button
        SourceFileName         matlab.ui.control.Label
        ReplacementFileButton  matlab.ui.control.Button
        OverlayFileName        matlab.ui.control.Label
        ReplacedFileButton     matlab.ui.control.Button
        ReplacementFileName    matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        Status                 matlab.ui.control.Label
        Crop                   matlab.ui.control.CheckBox
        RunButton              matlab.ui.control.Button
        PreviewButton          matlab.ui.control.Button
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XEditFieldLabel        matlab.ui.control.Label
        Image                  matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties for storing file paths and images
    properties (Access = private)
        sourceImagePath
        replacementImagePath
        replacedImagePath
        sourceImage
        replacementImage
        replacedImage
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SourceFileButton
        function selectSourceFile(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.mp4;*.avi;*.mov', 'Image and Video Files'});
            if isequal(file, 0)
                return;
            end
            app.sourceImagePath = fullfile(path, file);
            app.SourceFileName.Text = file;
            
            [~, ~, ext] = fileparts(file);
            if ismember(ext, {'.jpg', '.png', '.bmp'})
                app.sourceImage = imread(app.sourceImagePath);
            elseif ismember(ext, {'.mp4', '.avi'})
                app.sourceVideo = VideoReader(app.sourceImagePath);
                app.sourceImage = readFrame(app.sourceVideo);
            end
            if ~strcmp(app.SourceFileName.Text, 'No File Selected') && ~strcmp(app.OverlayFileName.Text, 'No File Selected') && ~strcmp(app.ReplacementFileName.Text, 'No File Selected')
                app.RunButton.Enable = 'on';
                app.PreviewButton.Enable = 'on';
            else
                app.RunButton.Enable = 'off';
                app.PreviewButton.Enable = 'off';
            end
        end

        % Button pushed function: ReplacementFileButton
        function selectReplacementFile(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.mp4;*.avi;*.mov', 'Image and Video Files'});
            if isequal(file, 0)
                return;
            end
            app.replacementImagePath = fullfile(path, file);
            app.OverlayFileName.Text = file;
            
            [~, ~, ext] = fileparts(file);
            if ismember(ext, {'.jpg', '.png', '.bmp'})
                app.replacementImage = imread(app.replacementImagePath);
            elseif ismember(ext, {'.mp4', '.avi'})
                app.overlayVideo = VideoReader(app.replacementImagePath);
                app.replacementImage = readFrame(app.overlayVideo);
            end
            if ~strcmp(app.SourceFileName.Text, 'No File Selected') && ~strcmp(app.OverlayFileName.Text, 'No File Selected') && ~strcmp(app.ReplacementFileName.Text, 'No File Selected')
                app.RunButton.Enable = 'on';
                app.PreviewButton.Enable = 'on';
            else
                app.RunButton.Enable = 'off';
                app.PreviewButton.Enable = 'off';
            end
        end

        % Button pushed function: ReplacedFileButton
        function selectReplacedFile(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'});
            if isequal(file, 0)
                return;
            end
            app.replacedImagePath = fullfile(path, file);
            app.ReplacementFileName.Text = file;
            app.replacedImage = imread(app.replacedImagePath);
            if ~strcmp(app.SourceFileName.Text, 'No File Selected') && ~strcmp(app.OverlayFileName.Text, 'No File Selected') && ~strcmp(app.ReplacementFileName.Text, 'No File Selected')
                app.RunButton.Enable = 'on';
                app.PreviewButton.Enable = 'on';
            else
                app.RunButton.Enable = 'off';
                app.PreviewButton.Enable = 'off';
            end
        end

        % Button pushed function: PreviewButton
        function previewButtonPushed(app, event)
            app.Status.Text = 'Please Wait...';

            if endsWith(app.sourceImagePath, {'.mov', '.mp4', '.avi'})
                sourceImg = readFrame(VideoReader(app.sourceImagePath));
            else
                sourceImg = imread(app.sourceImagePath);
            end
            if endsWith(app.replacementImagePath, {'.mov', '.mp4', '.avi'})
                overlayImg = readFrame(VideoReader(app.replacementImagePath));
            else
                overlayImg = imread(app.replacementImagePath);
            end

            replacementImg = imread(app.replacedImagePath);
            result_img = processAR(sourceImg, overlayImg, replacementImg, app.XEditField.Value, app.YEditField.Value, app.Crop.Value);

            if size(result_img, 3) == 1
                app.Image.ImageSource = repmat(result_img, 1, 1, 3);
            else
                app.Image.ImageSource = result_img;
            end

            app.Status.Text = 'Done!';
        end

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            if endsWith(app.sourceImagePath, {'.mov', '.mp4', '.avi'})
                sourceVideo = VideoReader(app.sourceImagePath);
                isSourceVideo = true;
            else
                sourceImg = imread(app.sourceImagePath);
                isSourceVideo = false;
            end
            if endsWith(app.replacementImagePath, {'.mov', '.mp4', '.avi'})
                overlayVideo = VideoReader(app.replacementImagePath);
                isOverlayVideo = true;
            else
                overlayImg = imread(app.replacementImagePath);
                isOverlayVideo = false;
            end

            if isSourceVideo || isOverlayVideo
                [file, path] = uiputfile('result.avi', 'Save As Video');
            else
                [file, path] = uiputfile('result.png', 'Save As Image');
            end
            if isequal(file, 0)
                return;
            end
            
            if isSourceVideo && isOverlayVideo
                totalFrames = min(sourceVideo.NumFrames, overlayVideo.NumFrames);
            elseif isSourceVideo
                totalFrames = sourceVideo.NumFrames;
            elseif isOverlayVideo
                totalFrames = overlayVideo.NumFrames;
            else
                totalFrames = 1;
            end

            outputFilePath = fullfile(path, file);
            if endsWith(outputFilePath, '.avi')
                outputVideo = VideoWriter(outputFilePath);
                open(outputVideo);
            end
            
            replacementImg = imread(app.replacedImagePath);
            for frameIndex = 1:totalFrames
                if isSourceVideo
                    sourceImg = read(sourceVideo, frameIndex);
                end
                if isOverlayVideo
                    overlayImg = read(overlayVideo, frameIndex);
                end
                
                result_img = processAR(sourceImg, overlayImg, replacementImg, app.XEditField.Value, app.YEditField.Value, app.Crop.Value);
                
                if size(result_img, 3) == 1
                    app.Image.ImageSource = repmat(result_img, 1, 1, 3);
                else
                    app.Image.ImageSource = result_img;
                end
                
                if endsWith(outputFilePath, '.avi')
                    writeVideo(outputVideo, result_img);
                elseif frameIndex == 1
                    imwrite(result_img, outputFilePath);
                end
                
                progress = (frameIndex / totalFrames) * 100;
                app.Status.Text = sprintf('Processing... %.2f%%', progress);
                drawnow;
            end
            
            if endsWith(outputFilePath, '.avi')
                close(outputVideo);
            end
            app.Status.Text = 'Done!';
        end

        % Value changed function: Crop
        function onCropCheckboxToggled(app, event)
            if app.Crop.Value
                app.YEditField.Enable = 'on';
                app.XEditField.Enable = 'on';
            else
                app.YEditField.Enable = 'off';
                app.XEditField.Enable = 'off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReplacementFileName = uilabel(app.LeftPanel);
            app.ReplacementFileName.Text = 'No File Selected';
            app.ReplacedFileButton = uibutton(app.LeftPanel, 'push');
            app.ReplacedFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectReplacedFile, true);
            app.ReplacedFileButton.Text = 'Select Replaced Image';
            app.OverlayFileName = uilabel(app.LeftPanel);
            app.OverlayFileName.Text = 'No File Selected';
            app.ReplacementFileButton = uibutton(app.LeftPanel, 'push');
            app.ReplacementFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectReplacementFile, true);
            app.ReplacementFileButton.Text = 'Select Replacement File';
            app.SourceFileName = uilabel(app.LeftPanel);
            app.SourceFileName.Text = 'No File Selected';
            app.SourceFileButton = uibutton(app.LeftPanel, 'push');
            app.SourceFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectSourceFile, true);
            app.SourceFileButton.Text = 'Select Source File';
            app.FILESLabel = uilabel(app.LeftPanel);
            app.FILESLabel.Text = 'FILES';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.XEditFieldLabel = uilabel(app.RightPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RightPanel, 'numeric');
            app.XEditField.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.RightPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RightPanel, 'numeric');
            app.YEditField.Enable = 'off';
            app.PreviewButton = uibutton(app.RightPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @previewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Text = 'Preview';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.Crop = uicheckbox(app.RightPanel);
            app.Crop.ValueChangedFcn = createCallbackFcn(app, @onCropCheckboxToggled, true);
            app.Crop.Text = 'Crop Overlay File';
            app.Status = uilabel(app.RightPanel);
            app.Status.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ar_homographies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end